classdef DAST_mods < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        TestTab                         matlab.ui.container.Tab
        W12Button                       matlab.ui.control.StateButton
        NextlistButton                  matlab.ui.control.Button
        W11Button                       matlab.ui.control.StateButton
        W10Button                       matlab.ui.control.StateButton
        W9Button                        matlab.ui.control.StateButton
        W8Button                        matlab.ui.control.StateButton
        W7Button                        matlab.ui.control.StateButton
        W6Button                        matlab.ui.control.StateButton
        W5Button                        matlab.ui.control.StateButton
        W4Button                        matlab.ui.control.StateButton
        W3Button                        matlab.ui.control.StateButton
        W2Button                        matlab.ui.control.StateButton
        W1Button                        matlab.ui.control.StateButton
        PauseButton                     matlab.ui.control.StateButton
        MarkallButton                   matlab.ui.control.StateButton
        Instructions                    matlab.ui.control.Label
        Sentences                       matlab.ui.control.Label
        currentSent                     matlab.ui.control.Label
        currentList                     matlab.ui.control.Label
        ContinueButton                  matlab.ui.control.Button
        DanskStningstestLabel           matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        SettingsTab                     matlab.ui.container.Tab
        SettingsPanelinSettingsTab      matlab.ui.container.Panel
        UploadedWorkspaceEditField      matlab.ui.control.EditField
        LoadworkspaceButton             matlab.ui.control.Button
        SaveworkspaceButton             matlab.ui.control.Button
        PlaybackmethodButtonGroup       matlab.ui.container.ButtonGroup
        ChannelsInEditField             matlab.ui.control.EditField
        ChannelsInEditFieldLabel        matlab.ui.control.Label
        DefaultButton                   matlab.ui.control.RadioButton
        AudioToolboxButton              matlab.ui.control.RadioButton
        PsychtoolboxButton              matlab.ui.control.RadioButton
        DriverDropDownLabel             matlab.ui.control.Label
        DriverDropDown                  matlab.ui.control.DropDown
        ChannelsOutEditField            matlab.ui.control.EditField
        ChannelsOutEditFieldLabel       matlab.ui.control.Label
        NoisesettingsButtonGroup        matlab.ui.container.ButtonGroup
        DurationbeforeandaftersentencesEditField  matlab.ui.control.EditField
        DurationbeforeandaftersentencesLabel  matlab.ui.control.Label
        secLabel                        matlab.ui.control.Label
        NoncontinuousButton             matlab.ui.control.RadioButton
        ContinuousButton                matlab.ui.control.RadioButton
        ScoringtypeButtonGroup          matlab.ui.container.ButtonGroup
        WordbywordButton                matlab.ui.control.RadioButton
        KeywordbasedButton              matlab.ui.control.RadioButton
        PresentationmodeButtonGroup     matlab.ui.container.ButtonGroup
        VideosetupButton                matlab.ui.control.Button
        AudiovisualButton               matlab.ui.control.RadioButton
        AudioButton                     matlab.ui.control.RadioButton
        CalibrationButtonGroup          matlab.ui.container.ButtonGroup
        ImportequalizationfilterButton  matlab.ui.control.Button
        FilterEditField                 matlab.ui.control.EditField
        FilterEditFieldLabel            matlab.ui.control.Label
        PlaycalibrationfileButton       matlab.ui.control.StateButton
        MeasuredLabel                   matlab.ui.control.Label
        CalibrationMeasuredEditField    matlab.ui.control.EditField
        dBZLabel                        matlab.ui.control.Label
        TestmethodButtonGroup           matlab.ui.container.ButtonGroup
        AdaptiveSNRButton               matlab.ui.control.RadioButton
        FixedSNRButton                  matlab.ui.control.RadioButton
        Panel                           matlab.ui.container.Panel
        currentAudioDir                 matlab.ui.control.EditField
        AudiopathEditFieldLabel         matlab.ui.control.Label
        ChooseButton                    matlab.ui.control.Button
        AudioSettingsTabs               matlab.ui.container.TabGroup
        QuickstartTab                   matlab.ui.container.Tab
        RandomizesentencesinlistCheckBox  matlab.ui.control.CheckBox
        MaskerlevelEditField            matlab.ui.control.EditField
        MaskerlevelEditFieldLabel       matlab.ui.control.Label
        TargetlevelEditField            matlab.ui.control.EditField
        TargetlevelEditFieldLabel       matlab.ui.control.Label
        MaskertypeDropDown              matlab.ui.control.DropDown
        MaskertypeDropDownLabel         matlab.ui.control.Label
        ListnumberDropDown              matlab.ui.control.DropDown
        ListnumberDropDownLabel         matlab.ui.control.Label
        TalkerDropDown                  matlab.ui.control.DropDown
        TalkerDropDownLabel             matlab.ui.control.Label
        dBSPLLabel_2                    matlab.ui.control.Label
        dBSPLLabel                      matlab.ui.control.Label
        AdvancedTab                     matlab.ui.container.Tab
        StartinglistDropDown            matlab.ui.control.DropDown
        StartinglistDropDownLabel       matlab.ui.control.Label
        UploadedCSVCheckBox             matlab.ui.control.CheckBox
        PleaseuploadacsvfilewiththecorrectformatLabel  matlab.ui.control.Label
        CSVfileEditField                matlab.ui.control.EditField
        CSVfileEditFieldLabel           matlab.ui.control.Label
        UploadButton                    matlab.ui.control.Button
        BasicinformationPanel           matlab.ui.container.Panel
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        ParticipantIDEditField          matlab.ui.control.EditField
        ParticipantIDEditFieldLabel     matlab.ui.control.Label
        TesterIDEditField               matlab.ui.control.EditField
        TesterIDEditFieldLabel          matlab.ui.control.Label
    end


    properties (Access = public)

        % % Definition of global variables

        % Main test variable (contains info, setup, results)
        data
        % Containing information of current test iteration
        tmp
   
    end

    methods (Access = public)

        function playSound(app)

            % Read the audio files
            [targetSignal,Fs_target] = audioread(app.tmp.target);
            [maskerSignal,Fs_masker] = audioread(app.tmp.masker);

            % Send error if Fs_target and Fs_masker are not as expected
            if Fs_target~=app.tmp.Fs || Fs_masker~=app.tmp.Fs
                error('The sampling frequency of your audio files is not supported.');
            end

            % Adjust padding if necessary
            if app.DurationbeforeandaftersentencesEditField ~= 0.5
                % TODO Add this feature later using app.DurationbeforeandaftersentencesEditField
            end

            % Select random segment of masker
            randSample = round((length(maskerSignal)-length(targetSignal))*rand);
            maskerSignal = maskerSignal(randSample:(randSample+length(targetSignal)-1));

            % Add ramping to masker
            maskerSignal(1:app.tmp.hannLen) = app.tmp.hannWindow(1:app.tmp.hannLen).*maskerSignal(1:app.tmp.hannLen);
            maskerSignal(end-app.tmp.hannLen+1:end) = app.tmp.hannWindow(app.tmp.hannLen+1:end).*maskerSignal(end-app.tmp.hannLen+1:end);

            % Apply equalization filter, if relevant
            if ~isempty(app.data.Setup.equalizationfilter)
                targetSignal = repmat(targetSignal,1,size(app.data.Setup.equalizationfilter,2));
                maskerSignal = repmat(maskerSignal,1,size(app.data.Setup.equalizationfilter,2));
                for cc = 1:size(app.data.Setup.equalizationfilter,2)
                    targetSignal(:,cc) = filter(app.data.Setup.equalizationfilter(:,cc),1,targetSignal(:,cc));
                    maskerSignal(:,cc) = filter(app.data.Setup.equalizationfilter(:,cc),1,maskerSignal(:,cc));
                end
            end

            % Scale the digital level of the target to get the desired physical level
            for cc = 1:size(targetSignal,2)
                targetGain = (app.tmp.targetLevel-app.data.Setup.dBSPLRef) + (app.data.Setup.dBSPLRef-app.data.Setup.calibrationMeasuredLevel(cc)); % calculate gain
                targetSignal(:,cc) = targetSignal(:,cc)*(10^(targetGain/20)); % apply gain to achieve desired physical level
            end

            % Scale the masker digital level to the desired physical level
            for cc = 1:size(maskerSignal,2)
                maskerGain = (app.tmp.maskerLevel-app.data.Setup.dBSPLRef) + (app.data.Setup.dBSPLRef-app.data.Setup.calibrationMeasuredLevel(cc)); % calculate gain
                maskerSignal(:,cc) = maskerSignal(:,cc)*(10^(maskerGain/20)); % apply gain to achieve desired physical level
            end

            % Combine target and masker
            mixtureSignal = targetSignal + maskerSignal;

            % TODO Keep these separate somehow to interface with
            % ChannelsOut vector so that the noise can be spatially
            % separated?

            % Play sound
            switch app.data.Setup.playbackMethod

                case 'Default'
                    
                    sound(mixtureSignal,app.tmp.Fs);
                    pause(4);
                    app.ContinueButton.Enable = "on";

                case 'AudioToolbox' % Audio Toolbox

                        % Play via Audio Toolbox
                        soundViaAudioToolbox(app,mixtureSignal);

                        % Prepare for recording, if relevant
                        if ~isempty(app.tmp.playbackChannelsIn)
                            app.tmp.playbackObjectFileWriter = dsp.AudioFileWriter(...
                                fullfile(app.tmp.resultRecordingsDir,app.tmp.targetFile),...
                                'FileFormat','WAV',...
                                'SampleRate',app.tmp.Fs);
                        end

                        % Enable continue button after end of sentence
                        app.ContinueButton.Enable = "on";

                        % Record the response, if relevant
                        if ~isempty(app.tmp.playbackChannelsIn)
                            pause(0);
                            tic;
                            recordingMaxSec = 60;
                            app.tmp.recordingFlag = true;
                            while app.tmp.recordingFlag==true && toc < recordingMaxSec
                                signalRecorded = app.tmp.playbackObjectRecorder();
                                app.tmp.playbackObjectFileWriter(signalRecorded);
                                drawnow limitrate; % update process for pending callbacks
                            end
                            release(app.tmp.playbackObjectRecorder);
                            release(app.tmp.playbackObjectFileWriter);
                        end
                      
                case 'Psychtoolbox'

                    % TODO

            end
        end
        
    end

    methods (Access = public)

        function updateInformation(app)

            app.tmp.iteration = app.tmp.iteration + 1; % current sentence
            app.tmp.whichLine = app.tmp.whichLine + 1; % for saving results

            if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab

                % TODO what happens when mixed talkers/mixed lists are
                % chosen.

            elseif app.AudioSettingsTabs.SelectedTab == app.AdvancedTab

                % Get current sentence number and text (from list)
                app.tmp.listNum = app.tmp.lists(app.tmp.list_iteration);
                app.tmp.sentences = app.tmp.sentencesFull(app.tmp.sentencesFull.List == app.tmp.listNum,:);

                % Get list/sentence individual settings
                app.tmp.talker = char(app.tmp.sentences.Talker(app.tmp.iteration)); % current talker
                app.tmp.maskerType = char(app.tmp.sentences.Masker(app.tmp.iteration)); % current masker

                % File locations:
                app.tmp.targetDir = fullfile(app.data.Setup.Audiopath,'Speech',...
                    app.tmp.talker); % targets dir
                app.tmp.maskerDir = fullfile(app.data.Setup.Audiopath,'Noise'); % masker dir

                app.tmp.targetLevel = app.tmp.sentences.("Target level")(app.tmp.iteration); % current target level
                app.tmp.maskerLevel = app.tmp.sentences.("Masker level")(app.tmp.iteration); % current masker level

            end

            % Get current sentence number and text
            app.tmp.sent = app.tmp.sentences.Number(app.tmp.iteration); % current sentence
            app.tmp.sentText = app.tmp.sentences.Sentence(app.tmp.iteration); % current text sentence
            app.tmp.sentTot = height(app.tmp.sentences); % total num of sentences per list

            % Update fields
            app.currentSent.Text = sprintf('Sentence %i (%i/%i)', app.tmp.sent, app.tmp.iteration, app.tmp.sentTot);
            app.currentList.Text = sprintf('List %i (%i/%i)', app.tmp.listNum, app.tmp.list_iteration, app.tmp.listTot);

            % ----------------------------------------------------

            % Buttons: keywords or word-based
            buttonSz = 8; % initial button size (wo. words)
            letterSz = 12; % increment for each letter in word
            wordSpacing = 4; % spacing between buttons (words)

            % make all buttons invisible
            for button = 1:app.tmp.totButtons
                app.(sprintf('W%iButton', button)).Visible = "off";
            end

            str = string(app.tmp.sentences.Sentence(app.tmp.iteration));
            Words = strsplit(str);
            nWords = numel(Words);
            nLetters = strlength(str);
            app.tmp.Keywords = string(app.tmp.sentences.Keywords(app.tmp.iteration));

            % initializing initial button position
            centerGrid = app.Sentences.Position(1)+ app.Sentences.Position(3)/2;
            totalSentWidth = buttonSz + wordSpacing + nLetters*(letterSz);
            initPosition = centerGrid - totalSentWidth/2;
            totalSize = initPosition;

            for i = 1:nWords

                % --- displaying each word onto buttons
                app.(sprintf('W%iButton', i)).Visible = "on";
                app.(sprintf('W%iButton', i)).Text = Words(i);

                % Keywords: find in which button and bold text + saving
                if contains(Words(i),strsplit(app.tmp.Keywords,', '),'IgnoreCase',true)
                    app.(sprintf('W%iButton', i)).FontWeight = 'bold';
                    app.tmp.KeywordsButtons = [app.tmp.KeywordsButtons i];
                end

                % changing width (Pos 3) ---
                app.(sprintf('W%iButton', i)).Position(3) = buttonSz+(letterSz*strlength(Words(i)));

                % --- spacing and locating each button (Pos 1) ---
                app.(sprintf('W%iButton', i)).Position(1) = totalSize;
                % current size + width of last button + space between words
                totalSize = totalSize + app.(sprintf('W%iButton', i)).Position(3) + wordSpacing;

            end

            % Disable non-keyword buttons (when keyword-based scoring)
            if app.data.Setup.scoringType == "Keyword-based"
                for m = 1:app.tmp.totButtons
                    if ~any(app.tmp.KeywordsButtons == m)
                        app.(sprintf('W%iButton', m)).Enable = "off";
                    end
                end
            end

            % ----------------------------------------------------

            % Taget and masker
            app.tmp.targetFile = sprintf('%04d.wav', app.tmp.sent);
            app.tmp.maskerFile = strcat(app.tmp.maskerType, '.wav');
            app.tmp.target = fullfile(app.tmp.targetDir, app.tmp.targetFile);
            app.tmp.masker = fullfile(app.tmp.maskerDir, app.tmp.maskerFile);

        end
    end

    methods (Access = private)

        function disableButtons_initialState(app)

            % User input enabled
            app.StartButton.Enable = "on";
            app.BasicinformationPanel.Enable = "on";
            app.Panel.Enable = "on";
            app.SettingsPanelinSettingsTab.Enable = "on";

            % Test tab disabled
            app.PauseButton.Enable = "off";
            app.StopButton.Enable = "off";
            app.NextlistButton.Enable = "off";
            app.MarkallButton.Enable = "off";

            app.tmp.KeywordsButtons =[];
            app.MarkallButton.Value = 0;
            app.PauseButton.Value = 0;

            for i = 1:app.tmp.totButtons
                app.(sprintf('W%iButton', i)).Visible = "off";
                app.(sprintf('W%iButton', i)).Value = 0;
                app.(sprintf('W%iButton', i)).FontWeight = 'normal';
            end

            app.MarkallButton.Visible = "off";
            app.NextlistButton.Visible = "off";
            app.Sentences.Visible = "off";
            app.Instructions.Visible = "off";
            app.currentList.Visible = "off";
            app.currentSent.Visible = "off";

            app.ContinueButton.Enable = "off";

        end

        function enableButtons_testState(app)

            % User input disabled
            app.StartButton.Enable = "off";
            app.BasicinformationPanel.Enable = "off";
            app.Panel.Enable = "off";
            app.SettingsPanelinSettingsTab.Enable = "off";

            % Test tab enabled
            app.PauseButton.Enable = "on";
            app.StopButton.Enable = "on";
            app.MarkallButton.Enable = "on";

            for i = 1:app.tmp.totButtons
                app.(sprintf('W%iButton', i)).Visible = "on";
                app.(sprintf('W%iButton', i)).Enable = "on";

            end
            app.MarkallButton.Visible = "on";
            app.NextlistButton.Visible = "off";
            app.Sentences.Visible = "on";
            app.Sentences.Text = 'Sentence';
            app.Sentences.FontSize = 14;
            app.Instructions.Visible = "on";
            app.currentList.Visible = "on";
            app.currentSent.Visible = "on";

        end

        
        function releaseAudioObjs(app)

            % Avoid crashing by releasing all existing audio objects
            clear sound

            if app.tmp.recordingFlag == true
                app.tmp.recordingFlag = false;
            end
            if ~isempty(app.tmp.playbackObjectPlayer)
                release(app.tmp.playbackObjectPlayer);
            end
            if ~isempty(app.tmp.playbackObjectFileReader)
                release(app.tmp.playbackObjectFileReader);
            end
            if exist(app.tmp.playbackTemporaryFile,'file')
                delete(app.tmp.playbackTemporaryFile);
            end
            if ~isempty(app.tmp.playbackObjectRecorder)
                release(app.tmp.playbackObjectRecorder);
            end
            if ~isempty(app.tmp.playbackObjectFileWriter)
                release(app.tmp.playbackObjectFileWriter);
            end
        end


        function getWorkspaceSetup(app)

            % Get workspace setup (that can be saved)
            app.data.Setup.Audiopath = app.currentAudioDir.Value;

            if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab

                % If quick start, read from drop down menu
                app.data.Setup.TestPresentation = 'QuickStart';

                if app.TalkerDropDown.Value == "Random talker"
                    app.data.Setup.talker = "Random talker";
                elseif app.TalkerDropDown.Value == "Mixed talkers"
                    app.data.Setup.talker = "Mixed talkers";
                else
                    % Take from drop down menu
                    app.data.Setup.talker = app.TalkerDropDown.Value;
                end

                if contains(app.MaskertypeDropDown.Value, "SSN")
                    % % TODO if SSN selected, choose correct file per talker
                    % whichMasker = strcat(app.data.Setup.maskerType, '_', app.Setup.talker);
                    % for now:
                    whichMasker = "SSN_S";
                    app.data.Setup.maskerType = whichMasker;
                else
                    app.data.Setup.maskerType = app.MaskertypeDropDown.Value;
                end

                if app.ListnumberDropDown.Value == "Random list"
                    app.data.Setup.listNum = "Random list";
                elseif app.ListnumberDropDown.Value == "Mixed list"
                    app.data.Setup.listNum = "Mixed list";
                else 
                    app.data.Setup.listNum = double(string(erase(app.ListnumberDropDown.Value, "List")));
                end
               
                if app.RandomizesentencesinlistCheckBox.Value == 1; app.data.Setup.RandomizedSentences = true;
                else; app.data.Setup.RandomizedSentences = false; end

                app.data.Setup.targetLevel = double(string(app.TargetlevelEditField.Value));
                app.data.Setup.maskerLevel = double(string(app.MaskerlevelEditField.Value));

            else
                % For advanced option
                app.data.Setup.TestPresentation = 'Advanced';
                app.data.Setup.listNum = 'Custom';
                app.data.Setup.talker = 'Custom';
                app.data.Setup.maskerType = 'Custom';
                app.data.Setup.targetLevel = 'Custom';
                app.data.Setup.maskerLevel = 'Custom';
            end

            % Settings
            app.data.Setup.calibrationMeasuredLevel = str2num(string(app.CalibrationMeasuredEditField.Value));
            app.data.Setup.equalizationfilter = app.FilterEditField.Value;
            app.data.Setup.maskerOnsetOffsetTime = double(string(app.DurationbeforeandaftersentencesEditField.Value)); % [sec.] time between noise onset and sentence start

            if app.ContinuousButton.Value == 1; app.data.Setup.NoiseSettings = 'Continuous';
            else; app.data.Setup.NoiseSettings = 'Noncontinuous'; end

            if app.FixedSNRButton.Value == 1; app.data.Setup.testMethod = 'Fixed SNR';
            else; app.data.Setup.testMethod = 'Adaptive SNR'; end

            if app.KeywordbasedButton.Value == 1; app.data.Setup.scoringType = 'Keyword-based';
            else; app.data.Setup.scoringType = 'Word-by-word'; end

            if app.AudioButton.Value == 1; app.data.Setup.presentationMode = 'Audio';
            else; app.data.Setup.presentationMode = 'Audiovisual'; end
            
            % Playback setup
            if app.DefaultButton.Value == 1; app.data.Setup.playbackMethod = 'Default';
            elseif app.AudioToolboxButton.Value == 1
                app.data.Setup.playbackMethod = 'AudioToolbox';
                app.data.Setup.playbackDriver = app.DriverDropDown.Value;
                app.data.Setup.playbackChannelsOut = str2num(string(app.ChannelsOutEditField.Value)); % keep str2num
                app.data.Setup.playbackChannelsIn = str2num(string(app.ChannelsInEditField.Value)); %str2double gives nan
            else; app.data.Setup.playbackMethod = 'Psychtoolbox';
            end

        end

        function setWorkspaceSetup(app)

            % Set workspace setup (that can be loaded)
            app.currentAudioDir.Value = app.data.Setup.Audiopath;

            if app.data.Setup.TestPresentation == "QuickStart"
                % If quick start, set drop down menu opts
                app.AudioSettingsTabs.SelectedTab = app.QuickstartTab;

                if app.data.Setup.talker == "Random talker"
                    app.TalkerDropDown.Value = "Random talker";
                elseif app.data.Setup.talker == "Mixed talkers"
                    app.TalkerDropDown.Value = "Mixed talkers";
                else
                    % Take from drop down menu
                    app.TalkerDropDown.Value = app.data.Setup.talker;
                end

                if contains(app.data.Setup.maskerType,"SSN")
                    % % TODO if SSN selected, choose correct file per talker
                    % for now:
                    whichMasker = "SSN";
                    app.MaskertypeDropDown.Value = whichMasker;
                else
                    app.MaskertypeDropDown.Value = app.data.Setup.maskerType;
                end

                if isa(app.data.Setup.listNum, 'numeric')
                    app.ListnumberDropDown.Value = strcat("List ", num2str(app.data.Setup.listNum));
                elseif app.data.Setup.listNum == "Random list"
                    app.ListnumberDropDown.Value = "Random list";
                elseif app.data.Setup.listNum == "Mixed list"
                    app.ListnumberDropDown.Value = "Mixed list";
                end

                if app.data.Setup.RandomizedSentences == true; app.RandomizesentencesinlistCheckBox.Value = 1;
                else; app.RandomizesentencesinlistCheckBox.Value = 0; end

                app.TargetlevelEditField.Value = num2str(app.data.Setup.targetLevel);
                app.MaskerlevelEditField.Value = num2str(app.data.Setup.maskerLevel);

            else
                % For advanced option
                app.AudioSettingsTabs.SelectedTab = app.AdvancedTab;
                app.CSVfileEditField.Value = '';
                app.UploadedCSVCheckBox.Value = 0;
            end

            % Settings
            app.CalibrationMeasuredEditField.Value = num2str(app.data.Setup.calibrationMeasuredLevel);
            app.FilterEditField.Value = app.data.Setup.equalizationfilter;
            app.DurationbeforeandaftersentencesEditField.Value = num2str(app.data.Setup.maskerOnsetOffsetTime);

            if app.data.Setup.NoiseSettings == "Continuous"; app.ContinuousButton.Value = 1; 
            else; app.NoncontinuousButton.Value = 1; end

            if app.data.Setup.testMethod == "Fixed SNR"; app.FixedSNRButton.Value = 1; 
            else; app.AdaptiveSNRButton.Value = 1; end

            if app.data.Setup.scoringType == "Keyword-based"; app.KeywordbasedButton.Value = 1; 
            else; app.WordbywordButton.Value = 1; end

            if app.data.Setup.presentationMode == "Audio"; app.AudioButton.Value = 1; 
            else; app.AudiovisualButton.Value = 1; end
            
            % Playback setup
            if app.data.Setup.playbackMethod == "Default"; app.DefaultButton.Value = 1; 
            elseif app.data.Setup.playbackMethod == "AudioToolbox"
                app.AudioToolboxButton.Value = 1;
                app.DriverDropDown.Value = app.data.Setup.playbackDriver;
                app.ChannelsOutEditField.Value = num2str(app.data.Setup.playbackChannelsOut);
                app.ChannelsInEditField.Value = num2str(app.data.Setup.playbackChannelsIn);
            else; app.PsychtoolboxButton.Value = 1;
            end

        end


        function getSettings(app)

            % Get information independent of workspace settings

            % Get basic information
            app.data.Setup.testerID = app.TesterIDEditField.Value;
            app.data.Setup.ParticipantID = app.ParticipantIDEditField.Value;
            app.data.Setup.Filename = app.FilenameEditField.Value;

            % Get config from 'Audio settings tabs' and 'Test/adv' panels
            if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab
                
                if app.data.Setup.talker == "Random talker"
%                     % TODO Pick a random talker
%                     app.tmp.talker = [];
                elseif app.data.Setup.talker == "Mixed talkers"
%                     % TODO create randomization vector for mixing talkers
%                     app.tmp.talker = [];
                else
                    app.tmp.talker = app.data.Setup.talker;
                end

                app.tmp.maskerType = app.data.Setup.maskerType;

                if isa(app.data.Setup.listNum, 'numeric')
                    app.tmp.listNum = app.data.Setup.listNum;
                elseif app.data.Setup.listNum == "Random list"
%                     % TODO pick a random list for specific talker
%                     app.tmp.listNum = [];
                elseif app.data.Setup.listNum == "Mixed list"
%                     % TODO mix sentences of different lists
%                     app.tmp.listNum = [];
                end
                app.tmp.targetLevel = app.data.Setup.targetLevel;
                app.tmp.maskerLevel = app.data.Setup.maskerLevel;

                % read file with DAST sentence
                sentences = readtable(fullfile(pwd, 'files', 'DAST-sentences.xlsx'));
                
                % sentences for current list
                app.tmp.sentences = sentences(sentences.List == app.tmp.listNum,:);
                app.tmp.listTot = 1;

                % File locations:
                app.tmp.targetDir = fullfile(app.data.Setup.Audiopath,'Speech',...
                    app.tmp.talker); 
                app.tmp.maskerDir = fullfile(app.data.Setup.Audiopath,'Noise');

            end

            % Setting up playback method
            switch app.data.Setup.playbackMethod

                case 'Default'

                    % No setup needed

                case 'AudioToolbox' % Matlab Audio Toolbox

                    % Check if dsp object already exists
                    if ~isempty(app.tmp.playbackObjectPlayer)
                        release(app.tmp.playbackObjectPlayer);
                    end
                    if ~isempty(app.tmp.playbackObjectRecorder)
                        release(app.tmp.playbackObjectRecorder);
                    end

                    % Create the handle to the device writer
                    if ~isempty(app.tmp.playbackChannelsOut)
                        app.tmp.playbackObjectPlayer = audioDeviceWriter(app.tmp.Fs,...
                            'Driver',app.tmp.playbackDriver,...
                            'ChannelMappingSource','Property',...
                            'ChannelMapping',app.tmp.playbackChannelsOut,...
                            'BufferSize',app.tmp.playbackBuffer);                            
                    end

                    % Create the handle to the device recorder, if relevant
                    if ~isempty(app.tmp.playbackChannelsIn)
                        app.tmp.playbackObjectRecorder = audioDeviceReader(app.tmp.Fs,...
                            'Driver',app.tmp.playbackDriver,...
                            'ChannelMappingSource','Property',...
                            'ChannelMapping',app.tmp.playbackChannelsIn,...
                            'SamplesPerFrame',app.tmp.playbackBuffer);
                    end

                    % Create the handle to the dsp.MatFileReader
                    app.tmp.playbackTemporaryFile = fullfile(app.tmp.resultDir,'DAST_temporary_file.mat');
                    app.tmp.playbackObjectFileReader = dsp.MatFileReader(app.tmp.playbackTemporaryFile,...
                        'VariableName','signalToPlay',...
                        'SamplesPerFrame',app.tmp.playbackBuffer);                          

                case 'Psychtoolbox'

                    % TODO
            end

            % ------------------------------------------------------------

            % Saving file location
            app.tmp.resultDir = fullfile(pwd, 'results'); % results dir
            if ~exist(app.tmp.resultDir, 'dir')
                mkdir(app.tmp.resultDir);
            end

            app.tmp.resultName = app.FilenameEditField.Value; % results file name
            app.tmp.resultFile = fullfile(app.tmp.resultDir, [app.tmp.resultName, '.csv']);

            % if csv file already exists (duplicating file name)
            newName = app.tmp.resultName;
            for i = 1:30
                if isfile(app.tmp.resultFile)
                    newName = strcat(app.tmp.resultName, '.', num2str(i));
                    app.tmp.resultFile = fullfile(app.tmp.resultDir, [newName, '.csv']);
                else
                    break
                end
            end
            if strcmp(app.tmp.resultName,newName) == 0
                app.tmp.resultName = newName;
            end

            % Make subfolder for recordings, if relevant
            if app.data.Setup.playbackMethod == "AudioToolbox"
                app.tmp.resultRecordingsDir = fullfile(app.tmp.resultDir,app.tmp.resultName);
                if ~isempty(app.tmp.playbackChannelsIn) && ~exist(app.tmp.resultRecordingsDir, 'dir')
                    mkdir(app.tmp.resultRecordingsDir);
                end
            end
        end


        function soundViaAudioToolbox(app,signalToPlay)

            % Duplicate signalToPlay, if necessary
            if isvector(signalToPlay) && length(app.tmp.playbackChannelsOut)>1 % mono
                signalToPlay = repmat(signalToPlay, 1, ...
                    length(app.tmp.playbackChannelsOut));
            elseif size(signalToPlay,2)==2 % L/R or two-channel
                if length(app.tmp.playbackChannelsOut)>2 && mod(length(app.tmp.playbackChannelsOut),2)==0 % duplicate L/R
                    signalToPlay = repmat(signalToPlay, 1, ...
                        length(app.tmp.playbackChannelsOut)/size(signalToPlay,2));
                end
            else % multi-channel
                % Do nothing
            end

            % Write temporary .mat file with the data
            save(app.tmp.playbackTemporaryFile,'signalToPlay','-v7.3');

            % Play sentence
            while ~isDone(app.tmp.playbackObjectFileReader)
                audio = step(app.tmp.playbackObjectFileReader);
                step(app.tmp.playbackObjectPlayer,audio);
            end
            release(app.tmp.playbackObjectFileReader);
            release(app.tmp.playbackObjectPlayer);
            delete(app.tmp.playbackTemporaryFile);

        end
        
        
        function saveBasicInformationInFile(app)

            % Write basic information and setup 
            % TODO eventually save .mat file in csv or xlsx from 
            
            % % Initialize Test variable
            % table with results
            varTypes = ["double", "double", "cell", "double", "double", "double", "double"];
            varNames = ["List", "Target", "Masker", "Target level", "Masker level", "Score", "listResult"];
            sz = [1, length(varNames)];
            app.data.Test.Results = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);

        end
        
        function savePartialResultsInFile(app)
            
            % % Save current results on Test variable
            if app.data.Setup.scoringType == "Word-by-word"
                % get words correctly repeated
                wordsDisplay = strsplit(string(app.tmp.sentText));
                numWordsSent = numel(wordsDisplay);
                wordsRating = zeros(1,numWordsSent);
                for i = 1:app.tmp.totButtons
                    if app.(sprintf('W%iButton', i)).Value == 1
                        wordsRating(i) = 1;
                    end
                end
                % save words and results in file
                for w = 1:numWordsSent
                    app.data.Test.Words(app.tmp.whichLine, w) = wordsDisplay(w);
                    app.data.Test.WordsScore(app.tmp.whichLine, w) = wordsRating(w);
                end
            end

            % For both scoring methods
            keywordsDisplay = strsplit(string(app.tmp.Keywords), ', ');
            numKeywordsSent = numel(keywordsDisplay);
            keywordsRating = zeros(1,numKeywordsSent);
            for j = 1:length(app.tmp.KeywordsButtons)
                if app.(sprintf('W%iButton', app.tmp.KeywordsButtons(j))).Value == 1
                    keywordsRating(j) = 1;
                end
            end
            % save keywords and results in file
            for w = 1:numKeywordsSent
                app.data.Test.Keywords(app.tmp.whichLine, w) = keywordsDisplay(w);
                app.data.Test.KeywordsScore(app.tmp.whichLine, w) = keywordsRating(w);
            end

            % save scores of current sentence
            if app.data.Setup.scoringType == "Word-by-word"
                numCorrectWords = sum(wordsRating);
                currentScore = (numCorrectWords/numWordsSent)*100;
            else
                numCorrectWords = sum(keywordsRating);
                currentScore = (numCorrectWords/3)*100;
            end

            if app.data.Setup.testMethod == "Fixed SNR"
                app.tmp.scores = [app.tmp.scores currentScore];
                app.tmp.totalScore = round(mean(app.tmp.scores));
%             else % TODO, save final SRT
            end

            % write results in current line
            app.data.Test.Results.List(app.tmp.whichLine) = app.tmp.listNum;
            app.data.Test.Results.Target(app.tmp.whichLine) = app.tmp.sent;
            app.data.Test.Results.Masker(app.tmp.whichLine) = cellstr(app.tmp.maskerType);
            app.data.Test.Results.("Target level")(app.tmp.whichLine) = app.tmp.targetLevel;
            app.data.Test.Results.("Masker level")(app.tmp.whichLine) = app.tmp.maskerLevel;
            app.data.Test.Results.Score(app.tmp.whichLine) = currentScore;

        end


        function saveFinalResultsInFile(app)

            if app.data.Setup.testMethod == "Fixed SNR"
                app.data.Test.Results.listResult(app.tmp.whichLine) = app.tmp.totalScore;
                % else % TODO, save final SRT
            end

            if contains(app.Sentences.Text, "complete") % means test was completed
                result = app.data;
                save(fullfile(app.tmp.resultDir, [app.tmp.resultName, '.mat']), 'result', '-mat')
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % TODO function for load and save setup

            % Shuffle randomization
            rng('shuffle');

            % Intializing variables
            [currentfilepath,~,~] = fileparts(mfilename('fullpath'));
            cd (currentfilepath)
            formatOut = 'yyyy_mm_dd';
            app.data.Setup.Date = datestr(now,formatOut);
            app.FilenameEditField.Value = sprintf(app.data.Setup.Date);
            app.currentAudioDir.Value = fullfile(pwd, 'audio');

            % File location for setup files
            app.tmp.setupDir = fullfile(pwd, 'files', 'setup files');
            if ~exist(app.tmp.setupDir, 'dir')
                mkdir(app.tmp.setupDir);
            end

            % Playback setup
            app.data.Setup.playbackChannelsIn = [];
            app.data.Setup.playbackChannelsOut = [];
            app.data.Setup.playbackDriver = '';
            app.tmp.playbackObjectPlayer = [];
            app.tmp.playbackObjectFileReader = [];
            app.tmp.playbackObjectRecorder = [];
            app.tmp.playbackObjectFileWriter = [];
            app.tmp.playbackTemporaryFile = '';
            app.tmp.playbackBuffer = 2048; % 16384
            app.tmp.recordingFlag = false;

            % Keywords and Buttons
            app.tmp.KeywordsButtons = [];
            app.tmp.totButtons = 12;

            % Calibration
            app.data.Setup.dBSPLRef = 95; % 95 dB SPL is equivalent to -26 dB FS
            app.data.Setup.dBFSRef = -26;

            % Ramping
            app.tmp.hannLen = 50e-3*48e3; % 50ms of ramping up and down of the masker (i.e., based on 48 kHz sampling freq)
            app.tmp.hannWindow = hann(2*50e-3*48e3);
            app.tmp.Fs = 48e3; % Fs = 48 kHz

            % disable buttons
            disableButtons_initialState(app)
            app.StartinglistDropDown.Visible = "off";
            app.StartinglistDropDownLabel.Visible = "off";

        end

        % Value changed function: FilenameEditField, 
        % ...and 2 other components
        function TestInfoChanged(app, event)
            if isempty(app.TesterIDEditField.Value); Leader = '';
            else; Leader = strcat('_', app.TesterIDEditField.Value); end
            if isempty(app.ParticipantIDEditField.Value); Subject = '';
            else; Subject = strcat(app.ParticipantIDEditField.Value, '_'); end

            app.FilenameEditField.Value = sprintf(strcat(Subject, app.data.Setup.Date, Leader));
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

%             try
                % Get Workspace settings
                getWorkspaceSetup(app)

                % Get information from user
                getSettings(app)

                % Enable buttons
                enableButtons_testState(app)
                app.Sentences.Text = 'Sentence';

                % Save basic information and setup in file
                saveBasicInformationInFile(app)
 
                % Update files names
                app.tmp.scores = [];
                app.tmp.totalScore = 0;

                app.tmp.iteration = 0; % current sentence
                app.tmp.whichLine = 0; % initializing line for saving results

                if app.AudioSettingsTabs.SelectedTab == app.AdvancedTab
                    chosenOption = find(app.tmp.lists == double(string(app.StartinglistDropDown.Value)));
                    app.tmp.list_iteration = chosenOption; % current list from dropdown
                else
                    app.tmp.list_iteration = 1; % current list
                end

                %--------------------------------------------------------
                updateInformation(app);

                % Play sound
                pause(0.1);
                playSound(app)

                %--------------------------------------------------------

%             catch
%                 if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab
%                     errordlg('Please check input parameters for quick start');
%                     disableButtons_initialState(app)
%                 elseif app.AudioSettingsTabs.SelectedTab == app.AdvancedTab
%                     errordlg('Please check input parameters for advanced options.');
%                     disableButtons_initialState(app)
%                 else
%                     errordlg('Please check input parameters.');
%                     disableButtons_initialState(app)
%                 end
%             end

        end

        % Value changed function: MarkallButton
        function MarkallButtonValueChanged(app, event)
            value = app.MarkallButton.Value;
            for i = 1:app.tmp.totButtons
                if app.(sprintf('W%iButton', i)).Visible == "on"
                    if (value == 1 && app.data.Setup.scoringType ~= "Keyword-based") ||...
                            (value == 1 && app.data.Setup.scoringType == "Keyword-based" && any(app.tmp.KeywordsButtons == i))
                        app.(sprintf('W%iButton', i)).Value = 1;
                    else
                        app.(sprintf('W%iButton', i)).Value = 0;
                    end
                end
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)

            app.ContinueButton.Enable = "off";

            % release audio objects
            releaseAudioObjs(app)

            % Save current results
            savePartialResultsInFile(app)

            for i = 1:app.tmp.totButtons
                app.(sprintf('W%iButton', i)).Value = 0;
                app.(sprintf('W%iButton', i)).FontWeight = 'normal';
                app.(sprintf('W%iButton', i)).Enable = 'on';
            end
            app.tmp.KeywordsButtons = [];
            app.MarkallButton.Value = 0;

            if app.tmp.iteration < app.tmp.sentTot 

                % next sentence
                %--------------------------------------------------------
                % Update file locations/names
                updateInformation(app);

                % Play sound
                pause(0.1);
                playSound(app);
                %--------------------------------------------------------

            elseif app.tmp.list_iteration < app.tmp.listTot

                % Save results for list
                saveFinalResultsInFile(app)

                % Going to next list by pressing button
                app.ContinueButton.Enable = "off";
                pause(0.01)
                app.NextlistButton.Visible = "on";
                app.NextlistButton.Enable = "on";

                app.tmp.scores = [];
                app.tmp.totalScore = 0;

                app.Sentences.Text = ['List ', num2str(app.tmp.listNum), ' done.'];
                app.Sentences.FontSize = 20;

                % make all buttons invisible
                for button = 1:app.tmp.totButtons
                    app.(sprintf('W%iButton', button)).Visible = "off";
                end
                app.PauseButton.Enable = "off";
                app.MarkallButton.Visible = "off";
                app.Instructions.Visible = "off";

            else
                % end of test
                disableButtons_initialState(app);
                pause(0.01)
                app.Sentences.Visible = "on";
                app.Sentences.Text = ['List ', num2str(app.tmp.listNum), ' done. Test completed.'];
                app.Sentences.FontSize = 20;

                % Save results for list/end of test
                saveFinalResultsInFile(app)

            end


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

            % release audio objects
            releaseAudioObjs(app)

            % disable buttons
            disableButtons_initialState(app)
            
            app.Sentences.Visible = "on";
            app.Sentences.Text = 'Test terminated';
            app.Sentences.FontSize = 20;

            % Save results for list/end of test
            saveFinalResultsInFile(app)

        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)

            % Pressing pause
            if app.PauseButton.Value == 1

                % release audio objects
                releaseAudioObjs(app)

                app.Sentences.Text = 'Test paused';
                app.Sentences.FontSize = 20;

                % make buttons invisible
                % saving info on which are currently visible
                app.tmp.activeButtons = zeros(1, app.tmp.totButtons);
                for button = 1:app.tmp.totButtons
                    if app.(sprintf('W%iButton', button)).Visible == "on"
                        app.tmp.activeButtons(button) = 1;
                    end
                    app.(sprintf('W%iButton', button)).Visible = "off";
                end
                app.ContinueButton.Enable = "off";
                app.MarkallButton.Visible = "off";
                app.Instructions.Visible = "off";

            else
                % Pressing again for continuing test enabling buttons
                app.Sentences.Text = 'Sentence';
                app.Sentences.FontSize = 14;

                % make all buttons visible
                for button = 1:app.tmp.totButtons
                    if app.tmp.activeButtons(button) == 1
                        app.(sprintf('W%iButton', button)).Visible = "on";
                    end
                end
                app.MarkallButton.Visible = "on";
                app.Instructions.Visible = "on";

                % Playing sentence again
                pause(0.1);
                playSound(app);

            end

        end

        % Value changed function: W1Button, W2Button, W3Button, W4Button, 
        % ...and 4 other components
        function wordSelected(app, event)
            values = zeros(1,app.tmp.totButtons);
            for i = 1:app.tmp.totButtons
                values(i) =  app.(sprintf('W%iButton', i)).Value;
            end
            if any(values == 0)
                app.MarkallButton.Value = 0;
            end

        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.csv');
            if filename==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure)
                return
            else
                try
                    app.CSVfileEditField.Value = fullfile(pathname,filename);
                    app.UploadedCSVCheckBox.Value = 1;
                    app.StartinglistDropDownLabel.Visible = "on";
                    app.StartinglistDropDown.Visible = "on";

                    % Read from csv file
                    opts = detectImportOptions(app.CSVfileEditField.Value);
                    opts.Encoding = 'UTF-8';
                    opts.VariableNamingRule = 'Preserve';
                    app.tmp.sentencesFull = readtable(app.CSVfileEditField.Value, opts);  % uploaded csv with test info
                    app.tmp.lists = unique(app.tmp.sentencesFull.List, 'stable'); % get lists
                    app.tmp.listTot = length(app.tmp.lists);

                    % Showing list options in menu
                    app.StartinglistDropDown.Items = cellstr(num2str(app.tmp.lists));

                    drawnow;
                    figure(app.UIFigure)

                catch
                    figure(app.UIFigure)
                    app.CSVfileEditField.Value = '';
                    app.UploadedCSVCheckBox.Value = 0;
                    app.StartinglistDropDownLabel.Visible = "off";
                    app.StartinglistDropDown.Visible = "off";
                    errordlg('Invalid CSV file. Please upload a file with the right format.');
                    drawnow;
                    disableButtons_initialState(app)
                end

                app.data.Setup.UploadedCSV = app.CSVfileEditField.Value;

            end
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            audiopath = uigetdir;

            if audiopath==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure)
                return
            else
                app.currentAudioDir.Value = audiopath;
                drawnow;
                figure(app.UIFigure)
            end

        end

        % Value changed function: PlaycalibrationfileButton
        function PlaycalibrationfileButtonPushed(app, event)

            % Handle radio buttons
            if app.DefaultButton.Value == 1; app.data.Setup.playbackMethod = 'Default';
            elseif app.AudioToolboxButton.Value == 1; app.data.Setup.playbackMethod = 'AudioToolbox';
            else; app.data.Setup.playbackMethod = 'Psychtoolbox';
            end

            value = app.PlaycalibrationfileButton.Value;

            if value == 0
                % if already pressed, stop sound
                releaseAudioObjs(app) % try this? not sure if it works to stop sound

            else
                % if pressed, play tone

                % Read calibration signal (TODO Change this to select speaker-specific SSN)
                [calibrationSignal,calibrationFs] = audioread(fullfile(app.data.Setup.Audiopath,'Noise','SSN_S.wav'));
                
                % TODO Add error if calibrationFs~=app.tmp.Fs

                % Apply equalization filter, if relevant
                if ~isempty(app.data.Setup.equalizationfilter)
                    calibrationSignal = repmat(calibrationSignal,1,size(app.data.Setup.equalizationfilter,2));
                    for cc = 1:size(app.data.Setup.equalizationfilter,2)
                        calibrationSignal(:,cc) = filter(app.data.Setup.equalizationfilter(:,cc),1,calibrationSignal(:,cc));
                    end
                end

                % Play the calibration file
                switch app.data.Setup.playbackMethod

                    case 'Default'

                        sound(calibrationSignal,calibrationFs);

                    case 'AudioToolbox'

                        soundViaAudioToolbox(app,calibrationSignal);

                    case 'Psychtoolbox'
                        
                        % TODO soundViaPsychtoolbox

                end

            end
            % TODO Add continuous looped playback

        end

        % Button pushed function: NextlistButton
        function NextlistButtonPushed(app, event)

            % if button pressed
            app.NextlistButton.Visible = "off";
            app.NextlistButton.Enable = "off";

            app.Sentences.Text = 'Sentence';
            app.Sentences.FontSize = 14;

            % make all buttons visible
            for button = 1:app.tmp.totButtons
                app.(sprintf('W%iButton', button)).Visible = "on";
            end
            app.ContinueButton.Enable = "off";
            app.PauseButton.Enable = "on";
            app.MarkallButton.Visible = "on";
            app.Instructions.Visible = "on";

            app.tmp.list_iteration = app.tmp.list_iteration + 1;
            app.tmp.iteration = 0;

            %--------------------------------------------------------
            % Update file locations/names
            updateInformation(app);

            % Play sound
            pause(0.1);
            playSound(app);
            %--------------------------------------------------------

        end

        % Selection changed function: PlaybackmethodButtonGroup
        function playbackMethodChanged(app, event)
            
            % Handle radio buttons
            if app.DefaultButton.Value == 1
                app.data.Setup.playbackMethod = 'Default';
                app.DriverDropDown.Enable = "off";
                app.DriverDropDownLabel.Enable = "off";
                app.ChannelsOutEditField.Enable = "off";
                app.ChannelsOutEditFieldLabel.Enable = "off";
                app.ChannelsInEditField.Enable = "off";
                app.ChannelsInEditFieldLabel.Enable = "off";    

                app.ChannelsOutEditField.Value = '';
                app.ChannelsInEditField.Value = '';

            elseif app.AudioToolboxButton.Value == 1
                app.data.Setup.playbackMethod = 'AudioToolbox';
                app.DriverDropDown.Enable = "on";
                app.DriverDropDownLabel.Enable = "on";
                app.ChannelsOutEditField.Enable = "on";
                app.ChannelsOutEditFieldLabel.Enable = "on";
                app.ChannelsInEditField.Enable = "on";
                app.ChannelsInEditFieldLabel.Enable = "on";

                if ispc % if windows
                    app.DriverDropDown.Items = {'ASIO', 'DirectSound', 'WASAPI'};
                elseif ismac % if mac
                    app.DriverDropDown.Items = {'CoreAudio'};
                else % if linux (isunix)
                    app.DriverDropDown.Items = {'ALSA'};
                end
            else
                app.data.Setup.playbackMethod = 'Psychtoolbox';
                app.DriverDropDown.Enable = "off";
                app.DriverDropDownLabel.Enable = "off";
                app.ChannelsOutEditField.Enable = "off";
                app.ChannelsOutEditFieldLabel.Enable = "off";
                app.ChannelsInEditField.Enable = "off";
                app.ChannelsInEditFieldLabel.Enable = "off";

                app.ChannelsOutEditField.Value = '';
                app.ChannelsInEditField.Value = '';
            end
        end

        % Selection changed function: PresentationmodeButtonGroup
        function presentationModeChanged(app, event)
            if app.AudioButton.Value == 1
                app.VideosetupButton.Enable = "off";
            else
                app.VideosetupButton.Enable = "on";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the window?', 'Close request');
            if strcmpi(YN,'OK')

                % release audio objects
                releaseAudioObjs(app)

                % Delete the app
                delete(app);
            end
        end

        % Button pushed function: ImportequalizationfilterButton
        function ImportequalizationfilterButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.mat');
            if filename==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                app.FilterEditField.Value = '';
                return
            else
                fullfilename = fullfile(pathname,filename);
                app.data.Setup.equalizationfilter = load(fullfilename);

                [~,f,~]=fileparts(fullfilename);
                filename=fullfile(f);
                app.FilterEditField.Value = filename;
   
                drawnow;
                figure(app.UIFigure);

            end
        end

        % Button pushed function: SaveworkspaceButton
        function SaveworkspaceButtonPushed(app, event)

            % Get user input for filename
            [file,path] = uiputfile(fullfile(app.tmp.setupDir,'*.mat'),'Save workspace setup');

            if file==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                return
            else
                % Get Workspace settings
                getWorkspaceSetup(app)
                setup = app.data.Setup;

                % Save current workspace file into mat file
                save(fullfile(path, file), 'setup', '-mat')
                drawnow;
                figure(app.UIFigure);
            end

        end

        % Button pushed function: LoadworkspaceButton
        function LoadworkspaceButtonPushed(app, event)
            
            [filename,pathname] = uigetfile(fullfile(app.tmp.setupDir,'*.mat'));
            if filename==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                app.UploadedWorkspaceEditField.Value = '';
                return
            else
                % loading variable
                fullfilename = fullfile(pathname,filename);
                load(fullfilename);
                app.data.Setup = setup;

                [~,f,~]=fileparts(fullfilename);
                filename=fullfile(f);

                app.UploadedWorkspaceEditField.Value = filename;
                drawnow;
                figure(app.UIFigure);

                % Updating workspace settings with current
                setWorkspaceSetup(app)
                presentationModeChanged(app, event)
                playbackMethodChanged(app, event)

            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BasicinformationPanel = uipanel(app.GridLayout);
            app.BasicinformationPanel.Title = 'Basic information';
            app.TesterIDEditFieldLabel = uilabel(app.BasicinformationPanel);
            app.TesterIDEditFieldLabel.Text = 'Tester ID';
            app.TesterIDEditField = uieditfield(app.BasicinformationPanel, 'text');
            app.TesterIDEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.ParticipantIDEditFieldLabel = uilabel(app.BasicinformationPanel);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.ParticipantIDEditField = uieditfield(app.BasicinformationPanel, 'text');
            app.ParticipantIDEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.FilenameEditFieldLabel = uilabel(app.BasicinformationPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.BasicinformationPanel, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.FilenameEditField.Editable = 'off';
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Tag = 'SettingsPanel';
            app.AudioSettingsTabs = uitabgroup(app.Panel);
            app.QuickstartTab = uitab(app.AudioSettingsTabs);
            app.QuickstartTab.Title = 'Quick start';
            app.dBSPLLabel = uilabel(app.QuickstartTab);
            app.dBSPLLabel.Text = 'dB SPL';
            app.dBSPLLabel_2 = uilabel(app.QuickstartTab);
            app.dBSPLLabel_2.Text = 'dB SPL';
            app.TalkerDropDownLabel = uilabel(app.QuickstartTab);
            app.TalkerDropDownLabel.Text = 'Talker';
            app.TalkerDropDown = uidropdown(app.QuickstartTab);
            app.TalkerDropDown.Items = {'Female 1', 'Female 2', 'Male 1', 'Male 2', 'Random talker', 'Mixed talkers'};
            app.TalkerDropDown.Value = 'Female 1';
            app.ListnumberDropDownLabel = uilabel(app.QuickstartTab);
            app.ListnumberDropDownLabel.Text = 'List number';
            app.ListnumberDropDown = uidropdown(app.QuickstartTab);
            app.ListnumberDropDown.Items = {'List 1', 'List 2', 'List 3', 'List 4', 'List 5', 'List 6', 'List 7', 'List 8', 'List 9', 'List 10', 'Random list', 'Mixed list'};
            app.ListnumberDropDown.Value = 'List 1';
            app.MaskertypeDropDownLabel = uilabel(app.QuickstartTab);
            app.MaskertypeDropDownLabel.Text = 'Masker type';
            app.MaskertypeDropDown = uidropdown(app.QuickstartTab);
            app.MaskertypeDropDown.Items = {'SSN', 'Unmodulated', 'Modulated', 'Sinusoidal', '4T-Babble'};
            app.MaskertypeDropDown.Value = 'SSN';
            app.TargetlevelEditFieldLabel = uilabel(app.QuickstartTab);
            app.TargetlevelEditFieldLabel.Text = 'Target level';
            app.TargetlevelEditField = uieditfield(app.QuickstartTab, 'text');
            app.TargetlevelEditField.Value = '70';
            app.MaskerlevelEditFieldLabel = uilabel(app.QuickstartTab);
            app.MaskerlevelEditFieldLabel.Text = 'Masker level';
            app.MaskerlevelEditField = uieditfield(app.QuickstartTab, 'text');
            app.MaskerlevelEditField.Value = '70';
            app.RandomizesentencesinlistCheckBox = uicheckbox(app.QuickstartTab);
            app.RandomizesentencesinlistCheckBox.Text = 'Randomize sentences in list';
            app.AdvancedTab = uitab(app.AudioSettingsTabs);
            app.AdvancedTab.Title = 'Advanced';
            app.UploadButton = uibutton(app.AdvancedTab, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.CSVfileEditFieldLabel = uilabel(app.AdvancedTab);
            app.CSVfileEditFieldLabel.Text = 'CSV file';
            app.CSVfileEditField = uieditfield(app.AdvancedTab, 'text');
            app.CSVfileEditField.Editable = 'off';
            app.PleaseuploadacsvfilewiththecorrectformatLabel = uilabel(app.AdvancedTab);
            app.PleaseuploadacsvfilewiththecorrectformatLabel.Text = {'Upload a csv file with the settings for the personalized test in the correct format.'; 'Obs. one CSV file per Subject No.'};
            app.UploadedCSVCheckBox = uicheckbox(app.AdvancedTab);
            app.UploadedCSVCheckBox.Enable = 'off';
            app.UploadedCSVCheckBox.Text = 'CheckUploadedCSV';
            app.StartinglistDropDownLabel = uilabel(app.AdvancedTab);
            app.StartinglistDropDownLabel.Text = 'Starting list';
            app.StartinglistDropDown = uidropdown(app.AdvancedTab);
            app.StartinglistDropDown.Items = {};
            app.StartinglistDropDown.Value = {};
            app.ChooseButton = uibutton(app.Panel, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.AudiopathEditFieldLabel = uilabel(app.Panel);
            app.AudiopathEditFieldLabel.Text = 'Audio path';
            app.currentAudioDir = uieditfield(app.Panel, 'text');
            app.currentAudioDir.Editable = 'off';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TestTab = uitab(app.TabGroup);
            app.TestTab.Title = 'Test';
            app.StartButton = uibutton(app.TestTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.TestTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.DanskStningstestLabel = uilabel(app.TestTab);
            app.DanskStningstestLabel.Text = 'Dansk Sætningstest';
            app.ContinueButton = uibutton(app.TestTab, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.currentList = uilabel(app.TestTab);
            app.currentList.Tag = 'currentList';
            app.currentList.Text = 'currentList';
            app.currentSent = uilabel(app.TestTab);
            app.currentSent.Tag = 'currentSent';
            app.currentSent.Text = 'currentSent';
            app.Sentences = uilabel(app.TestTab);
            app.Sentences.Text = 'Sentence';
            app.Instructions = uilabel(app.TestTab);
            app.Instructions.Text = 'Mark correctly repeated words';
            app.MarkallButton = uibutton(app.TestTab, 'state');
            app.MarkallButton.ValueChangedFcn = createCallbackFcn(app, @MarkallButtonValueChanged, true);
            app.MarkallButton.Text = 'Mark all';
            app.PauseButton = uibutton(app.TestTab, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.W1Button = uibutton(app.TestTab, 'state');
            app.W1Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W1Button.Text = 'W1';
            app.W2Button = uibutton(app.TestTab, 'state');
            app.W2Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W2Button.Text = 'W2';
            app.W3Button = uibutton(app.TestTab, 'state');
            app.W3Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W3Button.Text = 'W3';
            app.W4Button = uibutton(app.TestTab, 'state');
            app.W4Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W4Button.Text = 'W4';
            app.W5Button = uibutton(app.TestTab, 'state');
            app.W5Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W5Button.Text = 'W5';
            app.W6Button = uibutton(app.TestTab, 'state');
            app.W6Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W6Button.Text = 'W6';
            app.W7Button = uibutton(app.TestTab, 'state');
            app.W7Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W7Button.Text = 'W7';
            app.W8Button = uibutton(app.TestTab, 'state');
            app.W8Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W8Button.Text = 'W8';
            app.W9Button = uibutton(app.TestTab, 'state');
            app.W9Button.Text = 'W9';
            app.W10Button = uibutton(app.TestTab, 'state');
            app.W10Button.Text = 'W10';
            app.W11Button = uibutton(app.TestTab, 'state');
            app.W11Button.Text = 'W11';
            app.NextlistButton = uibutton(app.TestTab, 'push');
            app.NextlistButton.ButtonPushedFcn = createCallbackFcn(app, @NextlistButtonPushed, true);
            app.NextlistButton.Text = 'Next list';
            app.W12Button = uibutton(app.TestTab, 'state');
            app.W12Button.Text = 'W12';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsPanelinSettingsTab = uipanel(app.SettingsTab);
            app.SettingsPanelinSettingsTab.Tag = 'Panel for more test settings';
            app.TestmethodButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.TestmethodButtonGroup.Title = 'Test method';
            app.FixedSNRButton = uiradiobutton(app.TestmethodButtonGroup);
            app.FixedSNRButton.Text = 'Fixed SNR';
            app.FixedSNRButton.Value = true;
            app.AdaptiveSNRButton = uiradiobutton(app.TestmethodButtonGroup);
            app.AdaptiveSNRButton.Text = 'Adaptive SNR';
            app.CalibrationButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.CalibrationButtonGroup.Title = 'Calibration';
            app.dBZLabel = uilabel(app.CalibrationButtonGroup);
            app.dBZLabel.Text = 'dB(Z)';
            app.CalibrationMeasuredEditField = uieditfield(app.CalibrationButtonGroup, 'text');
            app.CalibrationMeasuredEditField.Value = '95';
            app.MeasuredLabel = uilabel(app.CalibrationButtonGroup);
            app.MeasuredLabel.Text = 'Measured';
            app.PlaycalibrationfileButton = uibutton(app.CalibrationButtonGroup, 'state');
            app.PlaycalibrationfileButton.ValueChangedFcn = createCallbackFcn(app, @PlaycalibrationfileButtonPushed, true);
            app.PlaycalibrationfileButton.Text = 'Play calibration file';
            app.FilterEditFieldLabel = uilabel(app.CalibrationButtonGroup);
            app.FilterEditFieldLabel.Text = 'Filter';
            app.FilterEditField = uieditfield(app.CalibrationButtonGroup, 'text');
            app.FilterEditField.Editable = 'off';
            app.ImportequalizationfilterButton = uibutton(app.CalibrationButtonGroup, 'push');
            app.ImportequalizationfilterButton.ButtonPushedFcn = createCallbackFcn(app, @ImportequalizationfilterButtonPushed, true);
            app.ImportequalizationfilterButton.Text = 'Import equalization filter';
            app.PresentationmodeButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.PresentationmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @presentationModeChanged, true);
            app.PresentationmodeButtonGroup.Title = 'Presentation mode';
            app.AudioButton = uiradiobutton(app.PresentationmodeButtonGroup);
            app.AudioButton.Text = 'Audio';
            app.AudioButton.Value = true;
            app.AudiovisualButton = uiradiobutton(app.PresentationmodeButtonGroup);
            app.AudiovisualButton.Text = 'Audiovisual';
            app.VideosetupButton = uibutton(app.PresentationmodeButtonGroup, 'push');
            app.VideosetupButton.Enable = 'off';
            app.VideosetupButton.Text = 'Video setup';
            app.ScoringtypeButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.ScoringtypeButtonGroup.Title = 'Scoring type';
            app.KeywordbasedButton = uiradiobutton(app.ScoringtypeButtonGroup);
            app.KeywordbasedButton.Text = 'Keyword-based';
            app.WordbywordButton = uiradiobutton(app.ScoringtypeButtonGroup);
            app.WordbywordButton.Text = 'Word-by-word';
            app.WordbywordButton.Value = true;
            app.NoisesettingsButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.NoisesettingsButtonGroup.Title = 'Noise settings';
            app.ContinuousButton = uiradiobutton(app.NoisesettingsButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.ContinuousButton.Value = true;
            app.NoncontinuousButton = uiradiobutton(app.NoisesettingsButtonGroup);
            app.NoncontinuousButton.Text = 'Noncontinuous';
            app.secLabel = uilabel(app.NoisesettingsButtonGroup);
            app.secLabel.Text = 'sec';
            app.DurationbeforeandaftersentencesLabel = uilabel(app.NoisesettingsButtonGroup);
            app.DurationbeforeandaftersentencesLabel.Text = {'Duration before'; 'and after sentences:'};
            app.DurationbeforeandaftersentencesEditField = uieditfield(app.NoisesettingsButtonGroup, 'text');
            app.DurationbeforeandaftersentencesEditField.Value = '0.5';
            app.PlaybackmethodButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.PlaybackmethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @playbackMethodChanged, true);
            app.PlaybackmethodButtonGroup.Title = 'Playback method';
            app.ChannelsOutEditFieldLabel = uilabel(app.PlaybackmethodButtonGroup);
            app.ChannelsOutEditFieldLabel.Enable = 'off';
            app.ChannelsOutEditFieldLabel.Text = 'Channel(s) out';
            app.ChannelsOutEditField = uieditfield(app.PlaybackmethodButtonGroup, 'text');
            app.ChannelsOutEditField.Enable = 'off';
            app.ChannelsOutEditField.Value = '3:6';
            app.DriverDropDown = uidropdown(app.PlaybackmethodButtonGroup);
            app.DriverDropDown.Items = {'ASIO', 'DirectSound', 'WASAPI', 'ALSA', 'CoreAudio'};
            app.DriverDropDown.Enable = 'off';
            app.DriverDropDown.Value = 'ASIO';
            app.DriverDropDownLabel = uilabel(app.PlaybackmethodButtonGroup);
            app.DriverDropDownLabel.Enable = 'off';
            app.DriverDropDownLabel.Text = 'Driver';
            app.PsychtoolboxButton = uiradiobutton(app.PlaybackmethodButtonGroup);
            app.PsychtoolboxButton.Text = 'Psychtoolbox';
            app.AudioToolboxButton = uiradiobutton(app.PlaybackmethodButtonGroup);
            app.AudioToolboxButton.Text = 'Audio Toolbox';
            app.DefaultButton = uiradiobutton(app.PlaybackmethodButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.DefaultButton.Value = true;
            app.ChannelsInEditFieldLabel = uilabel(app.PlaybackmethodButtonGroup);
            app.ChannelsInEditFieldLabel.Enable = 'off';
            app.ChannelsInEditFieldLabel.Text = 'Channel(s) in';
            app.ChannelsInEditField = uieditfield(app.PlaybackmethodButtonGroup, 'text');
            app.ChannelsInEditField.Enable = 'off';
            app.ChannelsInEditField.Value = '10';
            app.SaveworkspaceButton = uibutton(app.SettingsPanelinSettingsTab, 'push');
            app.SaveworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveworkspaceButtonPushed, true);
            app.SaveworkspaceButton.Text = 'Save workspace';
            app.LoadworkspaceButton = uibutton(app.SettingsPanelinSettingsTab, 'push');
            app.LoadworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadworkspaceButtonPushed, true);
            app.LoadworkspaceButton.Text = 'Load workspace';
            app.UploadedWorkspaceEditField = uieditfield(app.SettingsPanelinSettingsTab, 'text');
            app.UploadedWorkspaceEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAST_mods

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end